classdef sound_annotator_18427982 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Panel_11                  matlab.ui.container.Panel
        Audio2Label               matlab.ui.control.Label
        StopButton_2              matlab.ui.control.Button
        PauseButton_2             matlab.ui.control.Button
        PlayButton_3              matlab.ui.control.Button
        Panel_10                  matlab.ui.container.Panel
        StartSpinner_2            matlab.ui.control.Spinner
        StartSpinner_2Label       matlab.ui.control.Label
        OffsetLabel_2             matlab.ui.control.Label
        Panel_9                   matlab.ui.container.Panel
        audio2_axes               matlab.ui.control.UIAxes
        Panel_8                   matlab.ui.container.Panel
        RecordButton_2            matlab.ui.control.Button
        VolumeSlider_2            matlab.ui.control.Slider
        VolumeSlider_3Label       matlab.ui.control.Label
        UploadButton_2            matlab.ui.control.Button
        Panel_7                   matlab.ui.container.Panel
        Audio1Label               matlab.ui.control.Label
        StopButton_1              matlab.ui.control.Button
        PauseButton_1             matlab.ui.control.Button
        PlayButton_2              matlab.ui.control.Button
        Panel_6                   matlab.ui.container.Panel
        StartSpinner              matlab.ui.control.Spinner
        StartSpinnerLabel         matlab.ui.control.Label
        OffsetLabel               matlab.ui.control.Label
        Panel_2                   matlab.ui.container.Panel
        audio1_axes               matlab.ui.control.UIAxes
        Panel_4                   matlab.ui.container.Panel
        RecordButton              matlab.ui.control.Button
        VolumeSlider_1            matlab.ui.control.Slider
        VolumeSlider_2Label       matlab.ui.control.Label
        UploadButton              matlab.ui.control.Button
        Panel                     matlab.ui.container.Panel
        OutputAudioControlsLabel  matlab.ui.control.Label
        StopButton                matlab.ui.control.Button
        PauseButton               matlab.ui.control.Button
        PlayButton                matlab.ui.control.Button
        ExportButton              matlab.ui.control.Button
    end

    
    properties (Access = public)
        %global vars
        audio1 % Description
        audio1Player
        audio2
        audio2Player
        recorder
        finalAudioPlayer;
    end
    
    methods (Access = private)
        
        %function to set aspect enable to on when needed
        function results = enableButton(app, buttonName)
            set(buttonName, 'Enable', 'on');
        end
        
        %pause player if it is running else resume
        function results = pausePlayer(app, playerName)
            currently = isplaying(playerName);
            
            if(currently == 1)
                pause(playerName);
            elseif(currently == 0)
                resume(playerName);
            end
        end
        
        %stop player if it is running
        function results = stopPlayer(app, playerName)
            currently = isplaying(playerName);
            if(currently == 1)
               stop(playerName); 
            end
        end
        
        %play player
        function results = playPlayer(app, playerName)
            play(playerName);
        end
        
        %reset X axis then apply largest range to smaller X axis
        function results = redrawXLimits(app)
            
            %reset x axis using auto, allow matlab to choose the best range
            app.audio2_axes.XLimMode = 'auto';
            app.audio1_axes.XLimMode = 'auto';
            
            temp = app.audio1_axes.XLim(2);
            
            %apply the largest Xlim to the smallest axis
            if(temp > app.audio2_axes.XLim(2))
                app.audio2_axes.XLim = app.audio1_axes.XLim;
            else
                app.audio1_axes.XLim = app.audio2_axes.XLim;
            end
        end
        
        %calculate the offset needed for a given audio and value
        function results = calcReturnOffset(app, value, audioName)
            %get the length of value required to offset by
            samples = value * audioName.sampleRate;
            %fill 2 channels with zeros of required length
            offset = zeros([round(samples) 2]);
            results = offset;
        end
        
        %take calced offset and apply to start of audio file
        function results = offsetStart(app, value, audioName)
            
            offset = calcReturnOffset(app,value,audioName);
            %concat new audio based on uploaded audio
            audio = [offset; audioName.audio];
            
            results = audio;
        end
        
        %same as above but for end of audio, old feature to odd silence to
        %end of track removed from final build
        function results = offsetEnd(app, value, audioName)
            offset = calcReturnOffset(app,value,audioName);
            %concat new audio based on uploaded audio
            audio = [audioName.audio; offset];
            
            results = audio;
        end
        
        %calc relative change in volume for a given audio return audio
        function results = volumeChange(app, value, audio)
            %divide by 50 as the base/starting value, therefore calculating
            %relative change rather than a fixed value.
            realtiveValue = value/50;
            results = audio * realtiveValue;
        end
        
        %update player based on current volume and offset
        function results = audioChange1(app)
            spinvalue = app.StartSpinner.Value;
            volvalue = app.VolumeSlider_1.Value;
            
            audio = offsetStart(app, spinvalue, app.audio1);
            
            audio = volumeChange(app, volvalue, audio);
            %reset playe to new audio
            player = audioplayer(audio, app.audio1.sampleRate);
            app.audio1Player = player;
            
            %redraw plot
            plot(app.audio1_axes, audio);
            redrawXLimits(app);
            results = audio;
        end
        
        %update audio player 2 based on current volume and offset values
        function results = audioChange2(app)
            spinvalue = app.StartSpinner_2.Value;
            volvalue = app.VolumeSlider_2.Value;
            
            audio = offsetStart(app, spinvalue, app.audio2);
            
            audio = volumeChange(app, volvalue, audio);
            %reset player to new audio
            player = audioplayer(audio, app.audio2.sampleRate);
            app.audio2Player = player;
            %redraw plot
            plot(app.audio2_axes, audio);
            redrawXLimits(app);
            results = audio;
        end
        
        %if two tracks are uploaded/recorded enable merged audio functions
        function results = uploadCheck(app)
            if((app.PlayButton_2.Enable == true) && (app.PlayButton_3.Enable == true))
                enableButton(app, app.PlayButton);
                enableButton(app, app.PauseButton);
                enableButton(app, app.StopButton);
                enableButton(app, app.ExportButton);
            end
        end
        
        %when track 1 first setup enable buttons
        function results = setupAudio1(app, audio, samplerate)
            player = audioplayer(audio, samplerate);
            app.audio1Player = player;
            
            
            enableButton(app, app.PlayButton_2);
            enableButton(app, app.PauseButton_1);
            enableButton(app, app.StopButton_1);
            enableButton(app, app.StartSpinner);
            enableButton(app, app.VolumeSlider_1);
        end
        
        %when track 2 first setup enable buttons
        function results = setupAudio2(app, audio, samplerate)
            player = audioplayer(audio, samplerate);
            app.audio2Player = player;
            
            
            enableButton(app, app.PlayButton_3);
            enableButton(app, app.PauseButton_2);
            enableButton(app, app.StopButton_2);
            enableButton(app, app.StartSpinner_2);
            enableButton(app, app.VolumeSlider_2);
        end
        
        %initial axes setup
        function setupAxes(app, axes, title, audio)
            axes.Title.String = title;
                
            axes.XLimMode = 'auto';
            plot(axes, audio);
            redrawXLimits(app);
        end
        
        %calculate final audio track using all values
        function results = calcCreateFinalAudio(app)
            %get audio files based on current volume and offset
            %requirements
            audio_1 = audioChange1(app);
            audio_2 = audioChange2(app);
            %get the largest sample rate of the two files
            sampleRate = max(app.audio1.sampleRate, app.audio2.sampleRate);
            
            %resample as required
            if(~isequal(sampleRate, app.audio1.sampleRate))
                audio_1 = resample(audio_1, sampleRate, app.audio1.sampleRate);
            end
            
            if(~isequal(sampleRate, app.audio2.sampleRate))
                audio_2 = resample(audio_2, sampleRate, app.audio2.sampleRate);
            end
            
            %find length of audio tracks
            audio_1_length = length(audio_1) / sampleRate;
            audio_2_length = length(audio_2) / sampleRate;
            
            %find shortest aaudio file and length difference
            shortestAudio = audio_1;
            longestAudio = audio_2;
            lengthDiff =  audio_2_length - audio_1_length;
            
            if(audio_2_length < audio_1_length)
                shortestAudio = audio_2;
                longestAudio = audio_1;
                lengthDiff = audio_1_length - audio_2_length;
            end
            
            %use length diff to create an empty array of correct length
            samples = lengthDiff * sampleRate;
            %fill 2 channels with zeros of required length
            offset = zeros([round(samples) 2]);
            %concat new offset to shortest audio
            equalAudio = [shortestAudio ; offset];
            
            %merge audio 
            finalAudio = (equalAudio + longestAudio)/2;
            
            %update player
            app.finalAudioPlayer = audioplayer(finalAudio, sampleRate);
            
            results = finalAudio;
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            %produce audio upload dialog
            [filename, filepath] = uigetfile({'.wav'}, "Upload Audio");
            
            %prevent error when cancel clicked
            if (~isequal(filename, 0))
                selectedfile = fullfile(filepath,filename);
                [y, fs] = audioread(selectedfile);
                %set globals
                app.audio1.edited = y;
                app.audio1.audio = y;
                app.audio1.sampleRate = fs;
                %set player and axes
                setupAudio1(app, y, fs);
                setupAxes(app, app.audio1_axes, filename, y);
                
                uploadCheck(app);
            end
        end

        % Button pushed function: UploadButton_2
        function UploadButton_2Pushed(app, event)
            [filename, filepath] = uigetfile({'.wav'}, "Upload Audio");
            
            %prevents error when cancel is pressed as 0 is returned which
            %is obvs not a file path
            if (~isequal(filename, 0))
                selectedfile = fullfile(filepath,filename);
                [y, fs] = audioread(selectedfile);
                
                %set global vars
                app.audio2.edited = y;
                app.audio2.audio = y;
                app.audio2.sampleRate = fs;
                
                %setup axes and audio
                setupAudio2(app, y, fs);
                
                setupAxes(app, app.audio2_axes, filename, y);
                uploadCheck(app);
            end
        end

        % Button pushed function: PlayButton_2
        function PlayButton_2Pushed(app, event)
            %track 1 to play clicked
            playPlayer(app, app.audio1Player);           
        end

        % Button pushed function: PauseButton_1
        function PauseButton_1Pushed(app, event)
            %track 1 pause clicked
            pausePlayer(app, app.audio1Player);
        end

        % Button pushed function: StopButton_1
        function StopButton_1Pushed(app, event)
            %track 1 stop clicked
            stopPlayer(app, app.audio1Player);
        end

        % Value changed function: StartSpinner
        function StartSpinnerValueChanged(app, event)
            %spinner has stopped updating run for track 1 run audio change
            %for track 1
            audioChange1(app);
        end

        % Value changed function: StartSpinner_2
        function StartSpinner_2ValueChanged(app, event)
            %offset for track 2
            audioChange2(app);
        end

        % Button pushed function: PlayButton_3
        function PlayButton_3Pushed(app, event)
            %play track 2
            playPlayer(app, app.audio2Player);   
        end

        % Button pushed function: PauseButton_2
        function PauseButton_2Pushed(app, event)
            %pause track 2
            pausePlayer(app, app.audio2Player);
        end

        % Button pushed function: StopButton_2
        function StopButton_2Pushed(app, event)
            %stop track 2
            stopPlayer(app, app.audio2Player);
        end

        % Value changed function: VolumeSlider_1
        function VolumeSlider_1ValueChanged(app, event)
            %once volume change has stopped run audio change for track 1
            audioChange1(app);
        end

        % Value changed function: VolumeSlider_2
        function VolumeSlider_2ValueChanged(app, event)
            %track 2
            audioChange2(app);
        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            
            fs = 44100;
            
            if(app.RecordButton.Text == "Record")
                %create audio recorder with 2 channels at 44100 sample rate
                %change button text
                app.recorder = audiorecorder(fs, 16, 2);
                app.RecordButton.Text = "Stop";
                record(app.recorder);
                
            else
                %stop the recording and revert text
                stop(app.recorder);
                app.RecordButton.Text = "Record";
                
                %pull audio data
                y = getaudiodata(app.recorder);
                
                %setup globals
                app.audio1.edited = y;
                app.audio1.audio = y;
                app.audio1.sampleRate = fs;
                
                %setup players, buttons, axes
                setupAudio1(app, y, fs);
                setupAxes(app, app.audio1_axes, "Recording - Track 1", y);
                uploadCheck(app);
            end
            
        end

        % Button pushed function: RecordButton_2
        function RecordButton_2Pushed(app, event)
            fs = 44100;
            
            %if not already recording then record
            if(app.RecordButton_2.Text == "Record")
                %record audio
                app.recorder = audiorecorder(fs, 16, 2);
                app.RecordButton_2.Text = "Stop";
                record(app.recorder);
                %otherwise stop recording
            else
                %end recording and reverse button change
                stop(app.recorder);
                app.RecordButton_2.Text = "Record";
                
                %get audio data
                y = getaudiodata(app.recorder);
                
                %setup audio globals
                app.audio2.edited = y;
                app.audio2.audio = y;
                app.audio2.sampleRate = fs;
                
                %setup players, buttons and axes
                setupAudio2(app, y, fs);
                setupAxes(app, app.audio2_axes, "Recording - Track 2", y);
                uploadCheck(app);
                
            end
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            %create final audio file and play
            calcCreateFinalAudio(app);
            playPlayer(app, app.finalAudioPlayer);
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            %if play hasnt already been clicked then the audio has not yet
            %been created therefore player is not built yet.
            
            %not instantiated player returns a double as its file type
            %(default set to 0.0 or something as a guess)
            if(isa(app.finalAudioPlayer, 'double'))
                %if not created, create audio and play
                calcCreateFinalAudio(app);
                playPlayer(app, app.finalAudioPlayer);
            else
                %otherwise run pause player function
                pausePlayer(app, app.finalAudioPlayer);
            end
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % tilda is not in matlab for whatever reason
            if(~isa(app.finalAudioPlayer, 'double'))
            %checks to see if the audio player has been created yet, if it
            %has pass to stop player function
                stopPlayer(app, app.finalAudioPlayer);
            end
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            %produce final audio file
            finalAudio = calcCreateFinalAudio(app);
            
            %pull up ui for file name entry
            [file, folder] = uiputfile('*.wav', "Name Your Audio");
            
            %if input is is successful right audo based on location
            if (~isequal(file, 0))
                audiowrite(fullfile(folder, file), finalAudio, app.audio1.sampleRate);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.ExportButton = uibutton(app.Panel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Enable = 'off';
            app.ExportButton.Text = 'Export';
            app.PlayButton = uibutton(app.Panel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Enable = 'off';
            app.PlayButton.Text = 'Play';
            app.PauseButton = uibutton(app.Panel, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Enable = 'off';
            app.PauseButton.Text = 'Pause';
            app.StopButton = uibutton(app.Panel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.OutputAudioControlsLabel = uilabel(app.Panel);
            app.OutputAudioControlsLabel.Text = 'Output Audio Controls';
            app.Panel_4 = uipanel(app.UIFigure);
            app.UploadButton = uibutton(app.Panel_4, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.VolumeSlider_2Label = uilabel(app.Panel_4);
            app.VolumeSlider_2Label.Text = 'Volume';
            app.VolumeSlider_1 = uislider(app.Panel_4);
            app.VolumeSlider_1.ValueChangedFcn = createCallbackFcn(app, @VolumeSlider_1ValueChanged, true);
            app.VolumeSlider_1.Enable = 'off';
            app.VolumeSlider_1.Value = 50;
            app.RecordButton = uibutton(app.Panel_4, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.Panel_2 = uipanel(app.UIFigure);
            app.audio1_axes = uiaxes(app.Panel_2);
            title(app.audio1_axes, 'Audio 1')
            xlabel(app.audio1_axes, 'Seconds')
            ylabel(app.audio1_axes, 'Amplitude')
            app.Panel_6 = uipanel(app.UIFigure);
            app.OffsetLabel = uilabel(app.Panel_6);
            app.OffsetLabel.Text = 'Offset';
            app.StartSpinnerLabel = uilabel(app.Panel_6);
            app.StartSpinnerLabel.Text = 'Start';
            app.StartSpinner = uispinner(app.Panel_6);
            app.StartSpinner.Limits = [0 Inf];
            app.StartSpinner.ValueDisplayFormat = '%.2f';
            app.StartSpinner.ValueChangedFcn = createCallbackFcn(app, @StartSpinnerValueChanged, true);
            app.StartSpinner.Enable = 'off';
            app.Panel_7 = uipanel(app.UIFigure);
            app.PlayButton_2 = uibutton(app.Panel_7, 'push');
            app.PlayButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlayButton_2Pushed, true);
            app.PlayButton_2.Enable = 'off';
            app.PlayButton_2.Text = 'Play';
            app.PauseButton_1 = uibutton(app.Panel_7, 'push');
            app.PauseButton_1.ButtonPushedFcn = createCallbackFcn(app, @PauseButton_1Pushed, true);
            app.PauseButton_1.Enable = 'off';
            app.PauseButton_1.Text = 'Pause';
            app.StopButton_1 = uibutton(app.Panel_7, 'push');
            app.StopButton_1.ButtonPushedFcn = createCallbackFcn(app, @StopButton_1Pushed, true);
            app.StopButton_1.Enable = 'off';
            app.StopButton_1.Text = 'Stop';
            app.Audio1Label = uilabel(app.Panel_7);
            app.Audio1Label.Text = 'Audio 1';
            app.Panel_8 = uipanel(app.UIFigure);
            app.UploadButton_2 = uibutton(app.Panel_8, 'push');
            app.UploadButton_2.ButtonPushedFcn = createCallbackFcn(app, @UploadButton_2Pushed, true);
            app.UploadButton_2.Text = 'Upload';
            app.VolumeSlider_3Label = uilabel(app.Panel_8);
            app.VolumeSlider_3Label.Text = 'Volume';
            app.VolumeSlider_2 = uislider(app.Panel_8);
            app.VolumeSlider_2.ValueChangedFcn = createCallbackFcn(app, @VolumeSlider_2ValueChanged, true);
            app.VolumeSlider_2.Enable = 'off';
            app.VolumeSlider_2.Value = 50;
            app.RecordButton_2 = uibutton(app.Panel_8, 'push');
            app.RecordButton_2.ButtonPushedFcn = createCallbackFcn(app, @RecordButton_2Pushed, true);
            app.RecordButton_2.Text = 'Record';
            app.Panel_9 = uipanel(app.UIFigure);
            app.audio2_axes = uiaxes(app.Panel_9);
            title(app.audio2_axes, 'Audio 2')
            xlabel(app.audio2_axes, 'Seconds')
            ylabel(app.audio2_axes, 'Amplitude')
            app.Panel_10 = uipanel(app.UIFigure);
            app.OffsetLabel_2 = uilabel(app.Panel_10);
            app.OffsetLabel_2.Text = 'Offset';
            app.StartSpinner_2Label = uilabel(app.Panel_10);
            app.StartSpinner_2Label.Text = 'Start';
            app.StartSpinner_2 = uispinner(app.Panel_10);
            app.StartSpinner_2.Limits = [0 Inf];
            app.StartSpinner_2.ValueDisplayFormat = '%.2f';
            app.StartSpinner_2.ValueChangedFcn = createCallbackFcn(app, @StartSpinner_2ValueChanged, true);
            app.StartSpinner_2.Enable = 'off';
            app.Panel_11 = uipanel(app.UIFigure);
            app.PlayButton_3 = uibutton(app.Panel_11, 'push');
            app.PlayButton_3.ButtonPushedFcn = createCallbackFcn(app, @PlayButton_3Pushed, true);
            app.PlayButton_3.Enable = 'off';
            app.PlayButton_3.Text = 'Play';
            app.PauseButton_2 = uibutton(app.Panel_11, 'push');
            app.PauseButton_2.ButtonPushedFcn = createCallbackFcn(app, @PauseButton_2Pushed, true);
            app.PauseButton_2.Enable = 'off';
            app.PauseButton_2.Text = 'Pause';
            app.StopButton_2 = uibutton(app.Panel_11, 'push');
            app.StopButton_2.ButtonPushedFcn = createCallbackFcn(app, @StopButton_2Pushed, true);
            app.StopButton_2.Enable = 'off';
            app.StopButton_2.Text = 'Stop';
            app.Audio2Label = uilabel(app.Panel_11);
            app.Audio2Label.Text = 'Audio 2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sound_annotator_18427982

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end